classdef lemon_battery < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        TabGroup               matlab.ui.container.TabGroup
        mainscreenTab          matlab.ui.container.Tab
        voltageGauge           matlab.ui.control.Gauge
        voltageGaugeLabel      matlab.ui.control.Label
        InformationPanel       matlab.ui.container.Panel
        GoToQuizButton         matlab.ui.control.Button
        RandomTipFunFactLabel  matlab.ui.control.Label
        nexttipButton          matlab.ui.control.Button
        prevtipButton          matlab.ui.control.Button
        tip_text               matlab.ui.control.TextArea
        ControlsPanel          matlab.ui.container.Panel
        FruitDropDown          matlab.ui.control.DropDown
        FruitDropDownLabel     matlab.ui.control.Label
        RipenessSlider         matlab.ui.control.Slider
        RipenessSliderLabel    matlab.ui.control.Label
        outputLamp             matlab.ui.control.Lamp
        outputLampLabel        matlab.ui.control.Label
        Image                  matlab.ui.control.Image
        quizscreenTab          matlab.ui.container.Tab
        TextArea_2             matlab.ui.control.TextArea
        prevquestionButton     matlab.ui.control.Button
        nextquestionButton     matlab.ui.control.Button
        questionnLabel         matlab.ui.control.Label
        answerButtonGroup      matlab.ui.container.ButtonGroup
        option3Button          matlab.ui.control.RadioButton
        option2Button          matlab.ui.control.RadioButton
        option1Button          matlab.ui.control.RadioButton
        submitButton           matlab.ui.control.Button
        resultsscreenTab       matlab.ui.container.Tab
        learnsomemoreButton    matlab.ui.control.Button
        tryagainButton         matlab.ui.control.Button
        TextArea               matlab.ui.control.TextArea
    end


    properties (Access = private)
        num_questions int32
        question_index int32  % keeps track of the current question
        questions char % list of the questions
        options char % list of the options
        answers char % list of the correct answers
        points int32 % how much each question is worth
        selections int32
        fact_timer timer % timer to control the swapping of facts
        fact_index int32
        facts string

    end

    methods (Access = private)

        function timer_callback(app)
            n_facts = length(app.facts);

            if app.fact_index == n_facts
                app.fact_index = 1;
            else
                app.fact_index = app.fact_index + 1;
            end

            app.tip_text.Value = app.facts(app.fact_index);

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             app.fact_index = 1;
            app.facts = load('facts.mat').facts;
            app.fact_timer = timer;
            app.fact_timer.TimerFcn = @(varargin)app.timer_callback;
            app.fact_timer.ExecutionMode = "fixedRate";
            app.fact_timer.Period=10;
            start(app.fact_timer);
        end

        % Button pushed function: nextquestionButton
        function nextquestionButtonPushed(app, event)
            if app.question_index < app.num_questions
                app.question_index = app.question_index + 1;
            end


        end

        % Button pushed function: prevtipButton
        function prevtipButtonPushed(app, event)
            n_facts = length(app.facts);

            if app.fact_index == 1 
                app.fact_index =n_facts;
            else 
                app.fact_index = app.fact_index - 1;
            end
            
            app.tip_text.Value = app.facts(app.fact_index);


        end

        % Button pushed function: nexttipButton
        function nexttipButtonPushed(app, event)
            app.timer_callback();
        end

        % Value changed function: FruitDropDown
        function FruitDropDownValueChanged(app, event)
            value = app.FruitDropDown.Value;
            value = replace(value, " ", "");
            value = lower(value);
            
            path = append(value, ".png");

            app.Image.ImageSource = path;


        end

        % Button pushed function: GoToQuizButton
        function GoToQuizButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.quizscreenTab;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.mainscreenTab = uitab(app.TabGroup);
            app.mainscreenTab.Title = 'main screen';
            app.Image = uiimage(app.mainscreenTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'lemon.png');
            app.outputLampLabel = uilabel(app.mainscreenTab);
            app.outputLampLabel.Text = 'output';
            app.outputLamp = uilamp(app.mainscreenTab);
            app.ControlsPanel = uipanel(app.mainscreenTab);
            app.ControlsPanel.Title = 'Controls';
            app.RipenessSliderLabel = uilabel(app.ControlsPanel);
            app.RipenessSliderLabel.Text = 'Ripeness';
            app.RipenessSlider = uislider(app.ControlsPanel);
            app.FruitDropDownLabel = uilabel(app.ControlsPanel);
            app.FruitDropDownLabel.Text = 'Fruit';
            app.FruitDropDown = uidropdown(app.ControlsPanel);
            app.FruitDropDown.Items = {'Lemon', 'Lime', 'Grapefruit', 'Orange', 'Potato'};
            app.FruitDropDown.ValueChangedFcn = createCallbackFcn(app, @FruitDropDownValueChanged, true);
            app.FruitDropDown.Value = 'Lemon';
            app.InformationPanel = uipanel(app.mainscreenTab);
            app.InformationPanel.Title = 'Information';
            app.tip_text = uitextarea(app.InformationPanel);
            app.tip_text.Editable = 'off';
            app.tip_text.Value = {'you can get different voltages by changing the type of fruit, as well as the ripeness, etc.'};
            app.prevtipButton = uibutton(app.InformationPanel, 'push');
            app.prevtipButton.ButtonPushedFcn = createCallbackFcn(app, @prevtipButtonPushed, true);
            app.prevtipButton.Text = '←     prev tip';
            app.nexttipButton = uibutton(app.InformationPanel, 'push');
            app.nexttipButton.ButtonPushedFcn = createCallbackFcn(app, @nexttipButtonPushed, true);
            app.nexttipButton.Text = 'next tip     →';
            app.RandomTipFunFactLabel = uilabel(app.InformationPanel);
            app.RandomTipFunFactLabel.Text = 'Random Tip / Fun Fact';
            app.GoToQuizButton = uibutton(app.InformationPanel, 'push');
            app.GoToQuizButton.ButtonPushedFcn = createCallbackFcn(app, @GoToQuizButtonPushed, true);
            app.GoToQuizButton.Text = 'Go To Quiz';
            app.voltageGaugeLabel = uilabel(app.mainscreenTab);
            app.voltageGaugeLabel.Text = 'voltage';
            app.voltageGauge = uigauge(app.mainscreenTab, 'circular');
            app.quizscreenTab = uitab(app.TabGroup);
            app.quizscreenTab.Title = 'quiz screen';
            app.submitButton = uibutton(app.quizscreenTab, 'push');
            app.submitButton.Text = 'submit';
            app.answerButtonGroup = uibuttongroup(app.quizscreenTab);
            app.answerButtonGroup.Title = 'answer';
            app.option1Button = uiradiobutton(app.answerButtonGroup);
            app.option1Button.Text = 'option 1';
            app.option1Button.Value = true;
            app.option2Button = uiradiobutton(app.answerButtonGroup);
            app.option2Button.Text = 'option 2';
            app.option3Button = uiradiobutton(app.answerButtonGroup);
            app.option3Button.Text = 'option 3';
            app.questionnLabel = uilabel(app.quizscreenTab);
            app.questionnLabel.Text = 'question n/?';
            app.nextquestionButton = uibutton(app.quizscreenTab, 'push');
            app.nextquestionButton.ButtonPushedFcn = createCallbackFcn(app, @nextquestionButtonPushed, true);
            app.nextquestionButton.Text = 'next question';
            app.prevquestionButton = uibutton(app.quizscreenTab, 'push');
            app.prevquestionButton.Text = 'prev question';
            app.TextArea_2 = uitextarea(app.quizscreenTab);
            app.TextArea_2.Value = {'question text'};
            app.resultsscreenTab = uitab(app.TabGroup);
            app.resultsscreenTab.Title = 'results screen';
            app.TextArea = uitextarea(app.resultsscreenTab);
            app.TextArea.Value = {'you scored: ?/?!'};
            app.tryagainButton = uibutton(app.resultsscreenTab, 'push');
            app.tryagainButton.Text = 'try again';
            app.learnsomemoreButton = uibutton(app.resultsscreenTab, 'push');
            app.learnsomemoreButton.Text = 'learn some more';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lemon_battery

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end